classdef proje < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        SystemInfoLabel                 matlab.ui.control.Label
        SystemTransferFunctionTextArea  matlab.ui.control.TextArea
        SystemTransferFunctionTextAreaLabel  matlab.ui.control.Label
        Image                           matlab.ui.control.Image
        Ki2EditField                    matlab.ui.control.NumericEditField
        Ki2EditFieldLabel               matlab.ui.control.Label
        Kd2EditField                    matlab.ui.control.NumericEditField
        Kd2EditFieldLabel               matlab.ui.control.Label
        KaEditField                     matlab.ui.control.NumericEditField
        KaEditFieldLabel                matlab.ui.control.Label
        ControllerDropDown              matlab.ui.control.DropDown
        ControllerDropDownLabel         matlab.ui.control.Label
        EditField                       matlab.ui.control.EditField
        ResetButton                     matlab.ui.control.Button
        KdEditField                     matlab.ui.control.NumericEditField
        KdEditFieldLabel                matlab.ui.control.Label
        KiEditField                     matlab.ui.control.NumericEditField
        KiEditFieldLabel                matlab.ui.control.Label
        KpEditField                     matlab.ui.control.NumericEditField
        KpEditFieldLabel                matlab.ui.control.Label
        GraphDropDown                   matlab.ui.control.DropDown
        GraphDropDownLabel              matlab.ui.control.Label
        InputDropDown                   matlab.ui.control.DropDown
        InputDropDownLabel              matlab.ui.control.Label
        AnalysisButton                  matlab.ui.control.Button
        DenominatorEditField            matlab.ui.control.EditField
        DenominatorEditFieldLabel       matlab.ui.control.Label
        NumeratorEditField              matlab.ui.control.EditField
        NumeratorEditFieldLabel         matlab.ui.control.Label
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        numerator
        denominator
        H
        T
        Transfer
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.numerator=[];
            app.denominator=[];
            app.H=[1];
            app.ControllerDropDown.Value='Seçiniz';
            ControllerDropDownValueChanged(app,app.ControllerDropDown);
        end

        % Value changed function: NumeratorEditField
        function NumeratorEditFieldValueChanged(app, event)
            value = app.NumeratorEditField.Value;
             
       numeratorVector=str2num(value);
            
        end

        % Value changed function: DenominatorEditField
        function DenominatorEditFieldValueChanged(app, event)
            value = app.DenominatorEditField.Value;
            
           denominatorVector=str2num(value);
        end

        % Callback function: AnalysisButton, EditField, UIAxes, UIAxes2
        function AnalizButtonPushed(app, event)
            numeratorValue=app.NumeratorEditField.Value;
            denominatorValue=app.DenominatorEditField.Value;
            
            
            kp=app.KpEditField.Value;
            kd=app.KdEditField.Value;
            ki=app.KiEditField.Value;
            ka=app.KaEditField.Value;
            kd2=app.Kd2EditField.Value;
            ki2=app.Ki2EditField.Value;

           switch app.ControllerDropDown.Value
               case'Seçiniz'
                   f = msgbox("Kontrolcü Seçiniz");
                   s=tf('s')
                   app.Transfer=0;
               case 'P'
                   s = tf('s');
                   C_p = kp;
                   app.Transfer=C_p;

               case'PI'
                   s = tf('s');
                   C_pi = kp + ki/s ;
                   app.Transfer=C_pi;
               case'PD'
                   s = tf('s');
                   C_pd = kp + kd*s ;
                   app.Transfer=C_pd;
               case'PID'
                   s = tf('s');
                   C_pid = kp + ki/s + kd*s ;
                   app.Transfer=C_pid;
               case'PIDA'
                   s = tf('s');
                   C_pida = kp + ki/s + kd*s + ka*s^2;
                   app.Transfer=C_pida;
               case'PI2D'
                   s = tf('s');
                   C_pi2d = kp + ki/s + (ki2/s^2) + kd*s;
                   app.Transfer=C_pi2d;
               case'PI2D2'
                   s = tf('s');
                   C_pidd2 = kp + ki/s +(ki2*s^2)/(s^2)+ kd*s +(kd2*s^2);
                   app.Transfer=C_pidd2;
           end
             numeratorVector=NaN;
             numeratorVector=str2num(numeratorValue);
             denominatorVector=str2num(denominatorValue);


             Gp=tf(numeratorVector,denominatorVector);
             TF=Gp;
             M=feedback(app.Transfer*Gp,app.H);
             
             
             switch app.GraphDropDown.Value
                 case'Nyquist'
                     app.UIAxes2.Title.String='Nyquist Diyagram'
                     nyquist(app.UIAxes2,M);
                 case 'Sigma'   
                     app.UIAxes2.Title.String='Sigma Diyagram'
                     sigma(app.UIAxes2,M);
                 case 'Nichols'   
                     app.UIAxes2.Title.String='Nichols Diyagram'
                     nichols(app.UIAxes2,M);   
                 case 'Zeros/Poles'   
                     app.UIAxes2.Title.String='Zeros and Poles'
                     pzplot(app.UIAxes2,M);
                 otherwise
                     app.UIAxes2.Title.String='Bode Diyagramı'
                     bode(app.UIAxes2,M);
             end
             
            switch app.InputDropDown.Value
                case 'Step'
                    step(app.UIAxes,M); 
                otherwise 'İmpulse';
                    impulse(app.UIAxes,M);
            end

            info=stepinfo(M);
            
            app.EditField.Value=formattedDisplayText(info);
            
            
            sysString = evalc('Gp');
            app.SystemTransferFunctionTextArea.Value=sysString;
           
        end

        % Value changed function: KpEditField
        function KpEditFieldValueChanged(app, event)
            value = app.KpEditField.Value;
            
        end

        % Value changed function: KiEditField
        function KiEditFieldValueChanged(app, event)
            value = app.KiEditField.Value;
            
        end

        % Value changed function: KdEditField
        function KdEditFieldValueChanged(app, event)
            value = app.KdEditField.Value;
            
        end

        % Value changed function: EditField
        function EditFieldValueChanged(app, event)
            value = app.EditField.Value;
            
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
           cla(app.UIAxes);
           cla(app.UIAxes2);
           app.EditField.Value = "";
           app.SystemTransferFunctionTextArea.Value = "";
        end

        % Value changed function: ControllerDropDown
        function ControllerDropDownValueChanged(app, event)
            value = app.ControllerDropDown.Value;
            
            switch app.ControllerDropDown.Value
                case'Seçiniz'
                 app.KpEditField.Enable="off";
                 app.KdEditField.Enable="off";
                 app.KiEditField.Enable="off";
                 app.KaEditField.Enable="off";
                 app.Kd2EditField.Visible="off";
                 app.Ki2EditField.Visible="off";
                 app.Kd2EditFieldLabel.Visible="off";
                 app.Ki2EditFieldLabel.Visible="off";
                 app.Image.ImageSource='C:\Users\mecit\Desktop\tf\seçiniz.png';
                 
               case 'P'
                 app.KpEditField.Enable="on";
                 app.KdEditField.Enable="off";
                 app.KiEditField.Enable="off";
                 app.KaEditField.Enable="off";
                 app.Kd2EditField.Visible="off";
                 app.Ki2EditField.Visible="off";
                 app.Kd2EditFieldLabel.Visible="off";
                 app.Ki2EditFieldLabel.Visible="off";
                 app.Image.ImageSource='C:\Users\mecit\Desktop\tf\P.png';
               case'PI'
                 app.KpEditField.Enable="on";
                 app.KdEditField.Enable="off";
                 app.KiEditField.Enable="on";
                 app.KaEditField.Enable="off";
                 app.Kd2EditField.Visible="off";
                 app.Ki2EditField.Visible="off";
                 app.Kd2EditFieldLabel.Visible="off";
                 app.Ki2EditFieldLabel.Visible="off";
                 app.Image.ImageSource='C:\Users\mecit\Desktop\tf\PI.png';
               case'PD'
                 app.KpEditField.Enable="on";
                 app.KdEditField.Enable="on";
                 app.KiEditField.Enable="off";
                 app.KaEditField.Enable="off";
                 app.Kd2EditField.Visible="off";
                 app.Ki2EditField.Visible="off";
                 app.Kd2EditFieldLabel.Visible="off";
                 app.Ki2EditFieldLabel.Visible="off";
                 app.Image.ImageSource='C:\Users\mecit\Desktop\tf\PD.png';
               case'PID'
                 app.KpEditField.Enable="on";
                 app.KdEditField.Enable="on";
                 app.KiEditField.Enable="on";
                 app.KaEditField.Enable="off";
                 app.Kd2EditField.Visible="off";
                 app.Ki2EditField.Visible="off";
                 app.Kd2EditFieldLabel.Visible="off";
                 app.Ki2EditFieldLabel.Visible="off";
                 app.Image.ImageSource='C:\Users\mecit\Desktop\tf\PID.png';
               case'PIDA'
                 app.KpEditField.Enable="on";
                 app.KdEditField.Enable="on";
                 app.KiEditField.Enable="on";
                 app.KaEditField.Enable="on";
                 app.Kd2EditField.Visible="off";
                 app.Ki2EditField.Visible="off";
                 app.Kd2EditFieldLabel.Visible="off";
                 app.Ki2EditFieldLabel.Visible="off";
                 app.Image.ImageSource='C:\Users\mecit\Desktop\tf\PIDA.png';
               case'PI2D'
                 app.KpEditField.Enable="on";
                 app.KdEditField.Enable="on";
                 app.KiEditField.Enable="on";
                 app.KaEditField.Enable="off";
                 app.Kd2EditField.Visible="off";
                 app.Ki2EditField.Visible="on";
                 app.Kd2EditFieldLabel.Visible="off";
                 app.Ki2EditFieldLabel.Visible="on";
                 app.Image.ImageSource='C:\Users\mecit\Desktop\tf\PI2D.png';
               case'PI2D2'
                 app.KpEditField.Enable="on";
                 app.KdEditField.Enable="on";
                 app.KiEditField.Enable="on";
                 app.KaEditField.Enable="off";
                 app.Kd2EditField.Visible="on";
                 app.Ki2EditField.Visible="on";
                 app.Kd2EditFieldLabel.Visible="on";
                 app.Ki2EditFieldLabel.Visible="on";
                 app.Image.ImageSource='C:\Users\mecit\Desktop\tf\PI2D2.png';
           end
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'System response')
            xlabel(app.UIAxes, 'Time')
            ylabel(app.UIAxes, 'Amplitude')
            app.UIAxes.ButtonDownFcn = createCallbackFcn(app, @AnalizButtonPushed, true);
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Graph')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            app.UIAxes2.ButtonDownFcn = createCallbackFcn(app, @AnalizButtonPushed, true);
            app.NumeratorEditFieldLabel = uilabel(app.UIFigure);
            app.NumeratorEditFieldLabel.Text = 'Numerator';
            app.NumeratorEditField = uieditfield(app.UIFigure, 'text');
            app.NumeratorEditField.ValueChangedFcn = createCallbackFcn(app, @NumeratorEditFieldValueChanged, true);
            app.DenominatorEditFieldLabel = uilabel(app.UIFigure);
            app.DenominatorEditFieldLabel.Text = 'Denominator';
            app.DenominatorEditField = uieditfield(app.UIFigure, 'text');
            app.DenominatorEditField.ValueChangedFcn = createCallbackFcn(app, @DenominatorEditFieldValueChanged, true);
            app.AnalysisButton = uibutton(app.UIFigure, 'push');
            app.AnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @AnalizButtonPushed, true);
            app.AnalysisButton.Text = 'Analysis';
            app.InputDropDownLabel = uilabel(app.UIFigure);
            app.InputDropDownLabel.Text = 'Input';
            app.InputDropDown = uidropdown(app.UIFigure);
            app.InputDropDown.Items = {'Step', 'Impulse'};
            app.InputDropDown.Value = 'Step';
            app.GraphDropDownLabel = uilabel(app.UIFigure);
            app.GraphDropDownLabel.Text = 'Graph';
            app.GraphDropDown = uidropdown(app.UIFigure);
            app.GraphDropDown.Items = {'Nyquist', 'Bode', 'Sigma', 'Nichols', 'Zeros/Poles'};
            app.GraphDropDown.Value = 'Nyquist';
            app.KpEditFieldLabel = uilabel(app.UIFigure);
            app.KpEditFieldLabel.Text = 'Kp';
            app.KpEditField = uieditfield(app.UIFigure, 'numeric');
            app.KpEditField.ValueChangedFcn = createCallbackFcn(app, @KpEditFieldValueChanged, true);
            app.KiEditFieldLabel = uilabel(app.UIFigure);
            app.KiEditFieldLabel.Text = 'Ki';
            app.KiEditField = uieditfield(app.UIFigure, 'numeric');
            app.KiEditField.ValueChangedFcn = createCallbackFcn(app, @KiEditFieldValueChanged, true);
            app.KdEditFieldLabel = uilabel(app.UIFigure);
            app.KdEditFieldLabel.Text = 'Kd';
            app.KdEditField = uieditfield(app.UIFigure, 'numeric');
            app.KdEditField.ValueChangedFcn = createCallbackFcn(app, @KdEditFieldValueChanged, true);
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Text = 'Reset';
            app.EditField = uieditfield(app.UIFigure, 'text');
            app.EditField.ValueChangedFcn = createCallbackFcn(app, @EditFieldValueChanged, true);
            app.EditField.ValueChangingFcn = createCallbackFcn(app, @AnalizButtonPushed, true);
            app.EditField.Editable = 'off';
            app.ControllerDropDownLabel = uilabel(app.UIFigure);
            app.ControllerDropDownLabel.Text = 'Controller';
            app.ControllerDropDown = uidropdown(app.UIFigure);
            app.ControllerDropDown.Items = {'Seçiniz', 'P', 'PI', 'PD', 'PID', 'PIDA', 'PI2D', 'PI2D2'};
            app.ControllerDropDown.ValueChangedFcn = createCallbackFcn(app, @ControllerDropDownValueChanged, true);
            app.ControllerDropDown.Value = 'Seçiniz';
            app.KaEditFieldLabel = uilabel(app.UIFigure);
            app.KaEditFieldLabel.Text = 'Ka';
            app.KaEditField = uieditfield(app.UIFigure, 'numeric');
            app.Kd2EditFieldLabel = uilabel(app.UIFigure);
            app.Kd2EditFieldLabel.Text = 'Kd2';
            app.Kd2EditField = uieditfield(app.UIFigure, 'numeric');
            app.Ki2EditFieldLabel = uilabel(app.UIFigure);
            app.Ki2EditFieldLabel.Text = 'Ki2';
            app.Ki2EditField = uieditfield(app.UIFigure, 'numeric');
            app.Image = uiimage(app.UIFigure);
            app.SystemTransferFunctionTextAreaLabel = uilabel(app.UIFigure);
            app.SystemTransferFunctionTextAreaLabel.Text = {'System '; 'Transfer '; 'Function'};
            app.SystemTransferFunctionTextArea = uitextarea(app.UIFigure);
            app.SystemInfoLabel = uilabel(app.UIFigure);
            app.SystemInfoLabel.Text = 'System Info';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = proje

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end